classdef EqRec < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EquationRecUIFigure           matlab.ui.Figure
        ymaxEditField                 matlab.ui.control.NumericEditField
        ymaxEditFieldLabel            matlab.ui.control.Label
        yminEditField                 matlab.ui.control.NumericEditField
        yminEditFieldLabel            matlab.ui.control.Label
        Wykresy3DLabel                matlab.ui.control.Label
        Wykresy2DLabel                matlab.ui.control.Label
        RysujwykresButton             matlab.ui.control.Button
        xmaxEditField                 matlab.ui.control.NumericEditField
        xmaxEditFieldLabel            matlab.ui.control.Label
        xminEditField                 matlab.ui.control.NumericEditField
        xminEditFieldLabel            matlab.ui.control.Label
        ZapiszwynikButton             matlab.ui.control.Button
        SprawdnowsieButton            matlab.ui.control.Button
        WytrenujnowsieButton          matlab.ui.control.Button
        RozpoznanaSekwencjaEditField  matlab.ui.control.EditField
        RozpoznanaSekwencjaLabel      matlab.ui.control.Label
        RozpoznawaniesekwencjiButton  matlab.ui.control.Button
        WczytajobrazButton            matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        oldpath = addpath('./funkcje');
    end

    properties (Access = public)
        imageFile; 
        bim; 
        labels; 
        dict = ['1';'2'; '3'; '4'; '5'; '6'; '7'; '8'; '9';'0';'+';'/';'*';'-';'(';')';'^';'x'; 'y'];
        nn = load ('new_neural_network.mat'); % siec neuronowa
        % wspolczynniki
        fun = {@AO5RBlairBliss, @AO5RCircularityL, @AO5RCircularityS, @AO5RShape, @AO5RDanielsson, @AO5RHaralick, @AO5RMalinowska, @AO5TopEuler, @AO5BottomEuler,  @AO5Euler, @Centroid1,@Centroid2, @Eccentricity, @ConvexArea, @Solidity, @Extent, @Perimeter};
        train = {@train_nn};
    end
    
    methods (Access = private)
        function [gray_im,bim] = bin_im(app)
            app.bim = ~imbinarize(app.imageFile,0.8);
            app.bim = app.bim(:,:,1);
            app.bim = imopen(app.bim,ones(2));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WczytajobrazButton
        function WczytajobrazButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.*';'*.jpg';'*.png';'*.bmp'}, 'Choose file to load');
            figure(app.EquationRecUIFigure)
            FullFileName = [filepath filename];
          
            if ~isequal(filename,0)
                app.imageFile = double(imread(FullFileName))/255;
                imshow(app.imageFile,'parent',app.UIAxes);
                app.RozpoznanaSekwencjaEditField.Value = '';
            end

        end

        % Callback function
        function SegmentacjaButtonPushed(app, event)
            app.labels = bwlabel(app.bim);
            bin_im(app);
            imshow(label2rgb(app.labels),'parent',app.UIAxes);
        end

        % Button pushed function: RozpoznawaniesekwencjiButton
        function RozpoznawaniesekwencjiButtonPushed(app, event)
            test = ~imbinarize(app.imageFile,0.8);
            test = test(:,:,1);
            test = imopen(test,ones(2));
            a = regionprops(test,'Image');
            wtest = zeros(length(a), length(app.fun));
            for i = 1:length(a)
                for j = 1:length(app.fun)
                    binary_image = a(i).Image;
                    binary_image = padarray(binary_image,0);
                    binary_image = imresize(binary_image, [200, 200]);
                    wtest(i,j) =  app.fun{j}(binary_image);
                end
            end
            out1 = app.nn.nn(wtest');
            result = '';
            for i = 1:1:size(out1,2)
                result = strcat(result,string(app.dict(find(out1(:,i) == max(out1(:,i))))));
            end
            val = str2num(result);
            if(~isempty(val))
                val = sprintf("=%d", val);
            else
                val = "";
            end

            app.RozpoznanaSekwencjaEditField.Value = result + val;
        end

        % Button pushed function: WytrenujnowsieButton
        function WytrenujnowsieButtonPushed(app, event)
            app.train{1}();
            app.RozpoznanaSekwencjaEditField.Value = '';
        end

        % Button pushed function: SprawdnowsieButton
        function SprawdnowsieButtonPushed(app, event)
            nnNew = load('new_neural_network.mat');
            test = ~imbinarize(app.imageFile,0.95);
            test = imopen(test,ones(2));
            test = test(:,:,1);
            a = regionprops(test,'Image');
            wtest = zeros(length(a), length(app.fun));
            for i = 1:length(a)
                for j = 1:length(app.fun)
                    binary_image = a(i).Image;
                    binary_image = padarray(binary_image,0);
                    binary_image = imresize(binary_image, [200, 200]);
                    wtest(i,j) =  app.fun{j}(binary_image);
                end
            end
            out1 = nnNew.nn(wtest');
            result = '';
            for i = 1:1:size(out1,2)
                result = strcat(result,string(app.dict(find(out1(:,i) == max(out1(:,i))))));
            end

            val = str2num(result);
            if(~isempty(val))
                val = sprintf("=%d", val);
            else
                val = "";
            end

            app.RozpoznanaSekwencjaEditField.Value = result + val;
        end

        % Button pushed function: ZapiszwynikButton
        function ZapiszwynikButtonPushed(app, event)
            filter = {'*.txt';'*.*'};
            [file, path] = uiputfile(filter);
            figure(app.EquationRecUIFigure)
            filename = fullfile(path,file);
            if isequal(file,0) || isequal(path,0)
                disp('User clicked Cancel.')
            else
                fileWrite = fopen(filename,'wt');
                fprintf(fileWrite,app.RozpoznanaSekwencjaEditField.Value);
                fclose(fileWrite);
            end
        end

        % Button pushed function: RysujwykresButton
        function RysojWykresButtonPushed(app, event)
           n = 20;
            deltax = (app.xmaxEditField.Value-app.xminEditField.Value)/n;
            x = [app.xminEditField.Value: deltax: app.xmaxEditField.Value];

            func = app.RozpoznanaSekwencjaEditField.Value;
            if ( contains(func, 'x') && ~contains(func, 'y'))
                f = str2func(['@(x)' func]);
                y = f(x);
                plot(x, y);
            end
            if ( contains(func, 'y') && contains(func, 'x') )
                f = str2func(['@(x, y)' func]);
                deltay = (app.ymaxEditField.Value-app.yminEditField.Value)/n;
                y = [app.yminEditField.Value: deltay: app.ymaxEditField.Value];
                [X,Y] = meshgrid(x,y);
                
                for i = 1:n+1
                    for j = 1:n+1
                        T(i, j) = f(x(i), y(j));
                    end
                end
                surf(X,Y,T);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EquationRecUIFigure = uifigure('Visible', 'off');
            app.EquationRecUIFigure.Name = 'EquationRecognition';
            app.UIAxes = uiaxes(app.EquationRecUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.WczytajobrazButton = uibutton(app.EquationRecUIFigure, 'push');
            app.WczytajobrazButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajobrazButtonPushed, true);
            app.WczytajobrazButton.Text = 'Wczytaj obraz';
            app.RozpoznawaniesekwencjiButton = uibutton(app.EquationRecUIFigure, 'push');
            app.RozpoznawaniesekwencjiButton.ButtonPushedFcn = createCallbackFcn(app, @RozpoznawaniesekwencjiButtonPushed, true);
            app.RozpoznawaniesekwencjiButton.Text = 'Rozpoznawanie sekwencji';
            app.RozpoznanaSekwencjaLabel = uilabel(app.EquationRecUIFigure);
            app.RozpoznanaSekwencjaLabel.Text = 'Rozpoznana Sekwencja: ';
            app.RozpoznanaSekwencjaEditField = uieditfield(app.EquationRecUIFigure, 'text');
            app.RozpoznanaSekwencjaEditField.Value = 'NONE';
            app.WytrenujnowsieButton = uibutton(app.EquationRecUIFigure, 'push');
            app.WytrenujnowsieButton.ButtonPushedFcn = createCallbackFcn(app, @WytrenujnowsieButtonPushed, true);
            app.WytrenujnowsieButton.Text = 'Wytrenuj nową sieć';
            app.SprawdnowsieButton = uibutton(app.EquationRecUIFigure, 'push');
            app.SprawdnowsieButton.ButtonPushedFcn = createCallbackFcn(app, @SprawdnowsieButtonPushed, true);
            app.SprawdnowsieButton.Text = 'Sprawdź nową sieć';
            app.ZapiszwynikButton = uibutton(app.EquationRecUIFigure, 'push');
            app.ZapiszwynikButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszwynikButtonPushed, true);
            app.ZapiszwynikButton.Text = 'Zapisz wynik';
            app.xminEditFieldLabel = uilabel(app.EquationRecUIFigure);
            app.xminEditFieldLabel.Text = 'x min';
            app.xminEditField = uieditfield(app.EquationRecUIFigure, 'numeric');
            app.xminEditField.Value = -1;
            app.xmaxEditFieldLabel = uilabel(app.EquationRecUIFigure);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.EquationRecUIFigure, 'numeric');
            app.xmaxEditField.Value = 1;
            app.RysujwykresButton = uibutton(app.EquationRecUIFigure, 'push');
            app.RysujwykresButton.ButtonPushedFcn = createCallbackFcn(app, @RysojWykresButtonPushed, true);
            app.RysujwykresButton.Text = 'Rysuj wykres';
            app.Wykresy2DLabel = uilabel(app.EquationRecUIFigure);
            app.Wykresy2DLabel.Text = 'Wykresy 2D';
            app.Wykresy3DLabel = uilabel(app.EquationRecUIFigure);
            app.Wykresy3DLabel.Text = 'Wykresy 3D';
            app.yminEditFieldLabel = uilabel(app.EquationRecUIFigure);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.EquationRecUIFigure, 'numeric');
            app.yminEditField.Value = -1;
            app.ymaxEditFieldLabel = uilabel(app.EquationRecUIFigure);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.EquationRecUIFigure, 'numeric');
            app.ymaxEditField.Value = 1;
            % Show the figure after all components are created
            app.EquationRecUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EqRec

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EquationRecUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EquationRecUIFigure)
        end
    end
end