classdef TextRecog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TextRecognitionUIFigure    matlab.ui.Figure
        ymaxEditField              matlab.ui.control.NumericEditField
        ymaxEditFieldLabel         matlab.ui.control.Label
        yminEditField              matlab.ui.control.NumericEditField
        yminEditFieldLabel         matlab.ui.control.Label
        Wykresy3DLabel             matlab.ui.control.Label
        Wykresy2DLabel             matlab.ui.control.Label
        RysujwykresButton          matlab.ui.control.Button
        xmaxEditField              matlab.ui.control.NumericEditField
        xmaxEditFieldLabel         matlab.ui.control.Label
        xminEditField              matlab.ui.control.NumericEditField
        xminEditFieldLabel         matlab.ui.control.Label
        ZapiszwynikButton          matlab.ui.control.Button
        SprawdnowsieButton         matlab.ui.control.Button
        TrenujnowsieButton         matlab.ui.control.Button
        OutputEditField            matlab.ui.control.EditField
        OutputEditFieldLabel       matlab.ui.control.Label
        RozpoznawanietekstuButton  matlab.ui.control.Button
        WczytajobrazButton         matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    properties (Access = private)
        oldpath = addpath('./funkcje');
    end
    
    % zmienne widoczne w calej klasie, odwolanie app.bim
    properties (Access = public)
        imageFile; % Description
        bim; % binarny obraz
        labels; % etykiety
        % slownik liter
        %dict = ['1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; 'A'; 'B'; 'C'; 'D'; 'E'; 'F'; 'G'; 'H'; 'I'; 'J'; 'K'; 'L'; 'M'; 'N'; 'O'; 'P'; 'Q'; 'R'; 'S'; 'T'; 'U'; 'V'; 'W'; 'X'; 'Y'; 'Z'];
        %dict = ['1';'2'; '3'; '4'; '5'; '6'; '7'; '8'; '9';'0';'A'];
        dict = ['1';'2'; '3'; '4'; '5'; '6'; '7'; '8'; '9';'0';'+';'/';'*';'-';'(';')';'^';'x'; 'y'];
        nn = load ('ocr_final_8_new.mat'); % siec neuronowa
        % wspolczynniki
        fun = {@AO5RBlairBliss, @AO5RCircularityL, @AO5RCircularityS, @AO5RShape, @AO5RDanielsson, @AO5RHaralick, @AO5RMalinowska, @AO5TopEuler, @AO5BottomEuler,  @AO5Euler, @Centroid1,@Centroid2, @Eccentricity, @ConvexArea, @Solidity, @Extent, @Perimeter};
        wsp_train = {@wspolczynniki_train};
    end
    
    % metody widoczne w calej klasie, wywolanie bin_im(...)
    methods (Access = private)
        
        %binaryzacja
        function [gray_im,bim] = bin_im(app)
            app.bim = ~imbinarize(app.imageFile,0.8);
            app.bim = app.bim(:,:,1);
            app.bim = imopen(app.bim,ones(2));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WczytajobrazButton
        function WczytajobrazButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.*';'*.jpg';'*.png';'*.bmp'}, 'Choose file to load');
            figure(app.TextRecognitionUIFigure)
            FullFileName = [filepath filename];
          
            %load image if file selected
            if ~isequal(filename,0)
                app.imageFile = double(imread(FullFileName))/255;
                imshow(app.imageFile,'parent',app.UIAxes);
                app.OutputEditField.Value = '';
            end

        end

        % Callback function
        function SegmentacjaButtonPushed(app, event)
            app.labels = bwlabel(app.bim);
            bin_im(app);
            imshow(label2rgb(app.labels),'parent',app.UIAxes);
        end

        % Button pushed function: RozpoznawanietekstuButton
        function RozpoznawanietekstuButtonPushed(app, event)
            test = ~imbinarize(app.imageFile,0.8);
            test = test(:,:,1);
            test = imopen(test,ones(2));
            a = regionprops(test,'Image');
            wtest = zeros(length(a), length(app.fun));
            for i = 1:length(a)
                for j = 1:length(app.fun)
                    binary_image = a(i).Image;
                    binary_image = padarray(binary_image,0);
                    binary_image = imresize(binary_image, [200, 200]);
                    wtest(i,j) =  app.fun{j}(binary_image);
                end
            end
            out1 = app.nn.nn(wtest');
            result = '';
            for i = 1:1:size(out1,2)
                result = strcat(result,string(app.dict(find(out1(:,i) == max(out1(:,i))))));
            end
            app.OutputEditField.Value = result;
        end

        % Button pushed function: TrenujnowsieButton
        function TrenujnowsieButtonPushed(app, event)
            app.wsp_train{1}();
            app.OutputEditField.Value = '';
        end

        % Button pushed function: SprawdnowsieButton
        function SprawdnowsieButtonPushed(app, event)
            nnNew = load('ocr_final_8_new.mat');
            test = ~imbinarize(app.imageFile,0.95);
            test = imopen(test,ones(2));
            test = test(:,:,1);
            a = regionprops(test,'Image');
            wtest = zeros(length(a), length(app.fun));
            for i = 1:length(a)
                for j = 1:length(app.fun)
                    binary_image = a(i).Image;
                    binary_image = padarray(binary_image,0);
                    binary_image = imresize(binary_image, [200, 200]);
                    wtest(i,j) =  app.fun{j}(binary_image);
                end
            end
            out1 = nnNew.nn(wtest');
            result = '';
            for i = 1:1:size(out1,2)
                result = strcat(result,string(app.dict(find(out1(:,i) == max(out1(:,i))))));
            end
            app.OutputEditField.Value = result;
        end

        % Button pushed function: ZapiszwynikButton
        function ZapiszwynikButtonPushed(app, event)
            %function tranformed to loop training si
            result = "nie";
            while(strcmp(result, "123456789") == 0)
                app.wsp_train{1}();
                app.OutputEditField.Value = '';
                nnNew = load('ocr_final_8_new.mat');
                test = ~imbinarize(app.imageFile,0.9);
                test = imopen(test,ones(2));
                test = test(:,:,1);
                a = regionprops(test,'Image');
                wtest = zeros(length(a), length(app.fun));
                for i = 1:length(a)
                    for j = 1:length(app.fun)
                        binary_image = a(i).Image;
                        binary_image = padarray(binary_image,0);
                        binary_image = imresize(binary_image, [200, 200]);
                        wtest(i,j) =  app.fun{j}(binary_image);
                    end
                end
                out1 = nnNew.nn(wtest');
                result = '';
                for i = 1:1:size(out1,2)
                    result = strcat(result,string(app.dict(find(out1(:,i) == max(out1(:,i))))));
                end
            end
            m = 5;
        end

        % Button pushed function: RysujwykresButton
        function RysojWykresButtonPushed(app, event)
           n = 200;
            deltax = (app.xmaxEditField.Value-app.xminEditField.Value)/n;
            x = [app.xminEditField.Value: deltax: app.xmaxEditField.Value];

            func = app.OutputEditField.Value;
            if ( contains(func, 'x') )
                f = str2func(['@(x)' func]);
                y = f(x);
                plot(x, y);
            end
            if ( contains(func, 'y') && contains(func, 'x') )
                f = str2func(['@(x, y)' func]);
                deltay = (app.ymaxEditField.Value-app.yminEditField.Value)/n;
                y = [app.yminEditField.Value: deltay: app.ymaxEditField.Value];
                z = f(x, y);
                plot(x, y, z);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TextRecognitionUIFigure = uifigure('Visible', 'off');
            app.TextRecognitionUIFigure.Name = 'TextRecognition';
            app.UIAxes = uiaxes(app.TextRecognitionUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.WczytajobrazButton = uibutton(app.TextRecognitionUIFigure, 'push');
            app.WczytajobrazButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajobrazButtonPushed, true);
            app.WczytajobrazButton.Text = 'Wczytaj obraz';
            app.RozpoznawanietekstuButton = uibutton(app.TextRecognitionUIFigure, 'push');
            app.RozpoznawanietekstuButton.ButtonPushedFcn = createCallbackFcn(app, @RozpoznawanietekstuButtonPushed, true);
            app.RozpoznawanietekstuButton.Text = 'Rozpoznawanie tekstu';
            app.OutputEditFieldLabel = uilabel(app.TextRecognitionUIFigure);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.TextRecognitionUIFigure, 'text');
            app.OutputEditField.Value = 'NONE';
            app.TrenujnowsieButton = uibutton(app.TextRecognitionUIFigure, 'push');
            app.TrenujnowsieButton.ButtonPushedFcn = createCallbackFcn(app, @TrenujnowsieButtonPushed, true);
            app.TrenujnowsieButton.Text = 'Trenuj nową sieć';
            app.SprawdnowsieButton = uibutton(app.TextRecognitionUIFigure, 'push');
            app.SprawdnowsieButton.ButtonPushedFcn = createCallbackFcn(app, @SprawdnowsieButtonPushed, true);
            app.SprawdnowsieButton.Text = 'Sprawdź nową sieć';
            app.ZapiszwynikButton = uibutton(app.TextRecognitionUIFigure, 'push');
            app.ZapiszwynikButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszwynikButtonPushed, true);
            app.ZapiszwynikButton.Text = 'Zapisz wynik';
            app.xminEditFieldLabel = uilabel(app.TextRecognitionUIFigure);
            app.xminEditFieldLabel.Text = 'x min';
            app.xminEditField = uieditfield(app.TextRecognitionUIFigure, 'numeric');
            app.xminEditField.Value = -1;
            app.xmaxEditFieldLabel = uilabel(app.TextRecognitionUIFigure);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.TextRecognitionUIFigure, 'numeric');
            app.xmaxEditField.Value = 1;
            app.RysujwykresButton = uibutton(app.TextRecognitionUIFigure, 'push');
            app.RysujwykresButton.ButtonPushedFcn = createCallbackFcn(app, @RysojWykresButtonPushed, true);
            app.RysujwykresButton.Text = 'Rysuj wykres';
            app.Wykresy2DLabel = uilabel(app.TextRecognitionUIFigure);
            app.Wykresy2DLabel.Text = 'Wykresy 2D';
            app.Wykresy3DLabel = uilabel(app.TextRecognitionUIFigure);
            app.Wykresy3DLabel.Text = 'Wykresy 3D';
            app.yminEditFieldLabel = uilabel(app.TextRecognitionUIFigure);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.TextRecognitionUIFigure, 'numeric');
            app.yminEditField.Value = -1;
            app.ymaxEditFieldLabel = uilabel(app.TextRecognitionUIFigure);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.TextRecognitionUIFigure, 'numeric');
            app.ymaxEditField.Value = 1;
            % Show the figure after all components are created
            app.TextRecognitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TextRecog

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TextRecognitionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TextRecognitionUIFigure)
        end
    end
end